classdef Full < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        BuildTF_GUIUIFigure             matlab.ui.Figure
        FromHDF5EditFieldLabel          matlab.ui.control.Label
        FromFilePath                    matlab.ui.control.EditField
        ButtonChooseFromFile            matlab.ui.control.Button
        ToHDF5EditFieldLabel            matlab.ui.control.Label
        ToFilePath                      matlab.ui.control.EditField
        ButtonChooseToFile              matlab.ui.control.Button
        CheckToSameAsFromFile           matlab.ui.control.CheckBox
        PathFromEditFieldLabel          matlab.ui.control.Label
        FromDataPath                    matlab.ui.control.EditField
        PathToEditFieldLabel            matlab.ui.control.Label
        ToDataPath                      matlab.ui.control.EditField
        ButtonDisplayData               matlab.ui.control.Button
        ButtonPlotOutFigures            matlab.ui.control.Button
        ComputeButton                   matlab.ui.control.Button
        CheckShowLegend                 matlab.ui.control.CheckBox
        CheckShowTreatedData            matlab.ui.control.CheckBox
        PlotRunSpinnerLabel             matlab.ui.control.Label
        PlotRunSpinner                  matlab.ui.control.Spinner
        ResidualvalueEditFieldLabel     matlab.ui.control.Label
        ResidualvalueEditField          matlab.ui.control.NumericEditField
        PearsonvalueEditFieldLabel      matlab.ui.control.Label
        PearsonvalueEditField           matlab.ui.control.NumericEditField
        DataTFPanel                     matlab.ui.container.Panel
        FromPlot                        matlab.ui.control.UIAxes
        ToPlot                          matlab.ui.control.UIAxes
        TFPlot                          matlab.ui.control.UIAxes
        AboutButton                     matlab.ui.control.Button
        HelpButton                      matlab.ui.control.Button
        TabGroup                        matlab.ui.container.TabGroup
        PretreatmentTab                 matlab.ui.container.Tab
        ToPanel                         matlab.ui.container.Panel
        SGolayFilterptsLabel_2          matlab.ui.control.Label
        SGolayPointsToSpinner           matlab.ui.control.Spinner
        MedianFilterptsLabel_2          matlab.ui.control.Label
        MedianFilterToSpinner           matlab.ui.control.Spinner
        FromStepfunctiongeneratorPanel  matlab.ui.container.Panel
        UseStepCheckbox                 matlab.ui.control.CheckBox
        UpdateStepButton                matlab.ui.control.Button
        BaselinesSpinnerLabel           matlab.ui.control.Label
        BaselineStepSpinner             matlab.ui.control.Spinner
        UpsSpinnerLabel                 matlab.ui.control.Label
        UpStepSpinner                   matlab.ui.control.Spinner
        TotalsSpinnerLabel              matlab.ui.control.Label
        TotalSpinner                    matlab.ui.control.Spinner
        FromPanel                       matlab.ui.container.Panel
        SGolayFilterptsLabel            matlab.ui.control.Label
        SGolayPointsFromSpinner         matlab.ui.control.Spinner
        MedianFilterptsLabel            matlab.ui.control.Label
        MedianFilterFromSpinner         matlab.ui.control.Spinner
        InterpolationMethodDropDownLabel  matlab.ui.control.Label
        InterpolationMethodDropDown     matlab.ui.control.DropDown
        NewdTmsSpinnerLabel             matlab.ui.control.Label
        deltaT                          matlab.ui.control.Spinner
        Settingtheparametersvalueto0cancelthefilteringLabel  matlab.ui.control.Label
        BothPanel                       matlab.ui.container.Panel
        RangefromsLabel                 matlab.ui.control.Label
        rangeDataBegin                  matlab.ui.control.Spinner
        toLabel                         matlab.ui.control.Label
        rangeDataEnd                    matlab.ui.control.Spinner
        TFComputationTab                matlab.ui.container.Tab
        TFComputationPanel              matlab.ui.container.Panel
        DurationsLabel                  matlab.ui.control.Label
        durationTF                      matlab.ui.control.Spinner
        NumberofrunsSpinnerLabel        matlab.ui.control.Label
        NumberofrunsSpinner             matlab.ui.control.Spinner
        FunctionDropDownLabel           matlab.ui.control.Label
        FunctionDropDown                matlab.ui.control.DropDown
        AlgorithmDropDownLabel          matlab.ui.control.Label
        AlgorithmDropDown               matlab.ui.control.DropDown
        UseLastComputParamsAsInitialButton  matlab.ui.control.Button
        twiceOpt                        matlab.ui.control.CheckBox
        SetParamsTable                  matlab.ui.control.Table
        ComputedParamsTable             matlab.ui.control.Table
        EditFieldLabel                  matlab.ui.control.Label
        paramFunctionStrEditField       matlab.ui.control.EditField
        SavingTab                       matlab.ui.container.Tab
        SavingPanel                     matlab.ui.container.Panel
        MouseEditFieldLabel             matlab.ui.control.Label
        MouseEditField                  matlab.ui.control.EditField
        TechniqueEditFieldLabel         matlab.ui.control.Label
        TechniqueEditField              matlab.ui.control.EditField
        StimulationEditFieldLabel       matlab.ui.control.Label
        StimulationEditField            matlab.ui.control.EditField
        SaveButton                      matlab.ui.control.Button
        FileEditFieldLabel              matlab.ui.control.Label
        SavingFileEditField             matlab.ui.control.EditField
        ButtonChooseSaveFile            matlab.ui.control.Button
    end

    
    properties (Access = private)
        data = struct(); % Description
        smthgComputed = false; % Description
        smthgLoaded % Description
        PARAM_FUNCTIONS_FILE = 'DefaultParametricFunctions_Iliski.txt'; % Description
        parametricFctHandles = {}; % Description
        NON_PARAM_ALGO = {'fourier', 'toeplitz'};
        computationParamDefaultValues = {}; % Description
    end
    
    methods (Access = private)
        
        function results = loadSingledataset(app, filePath, dataPath)
            info = h5info(filePath, dataPath);
            results{1} = info.Name;
            results{2} = getTimeFromPath(filePath, dataPath);
            results{3} = h5read(filePath, dataPath);
        end
        
        
        function results = updatePlots(app)
            assignin('base', 'appData', app.data);
            
            run = app.PlotRunSpinner.Value;
            % Data Plots
            cla(app.FromPlot); cla(app.ToPlot); cla(app.TFPlot);
            hold(app.FromPlot, 'on'); hold(app.ToPlot, 'on'); hold(app.TFPlot, 'on');
            
            % For each timecourse in From = each From Paths
            if app.smthgComputed
                nameFrom = app.data.From{1};
                nameTo = app.data.To{1};
                
                if app.CheckShowTreatedData.Value
                    tmpFrom = app.data.lastComput{run}.fromDataTreated(:, :);
                    tmpTo = app.data.lastComput{run}.toDataTreated(:, :);
                else
                    tmpFrom = app.data.lastComput{run}.fromDataRaw(:, :);
                    tmpTo = app.data.lastComput{run}.toDataRaw(:, :);
                end
                
                plot(app.FromPlot, tmpFrom(:, 1), tmpFrom(:, 2), 'DisplayName', nameFrom, 'LineWidth', 2, 'Color', [0, 0.4470, 0.7410]);
                plot(app.ToPlot, tmpTo(:, 1), tmpTo(:, 2), 'DisplayName', nameTo, 'LineWidth', 2, 'Color', [0.9290, 0.6940, 0.1250]);
                
                % Convolving the From Data with the TF corresponding to the selected run (the last one by default)
                pred = conv(app.data.lastComput{run}.fromDataTreated(:, 2), app.data.lastComput{run}.TF);
                pred = pred(1:length(app.data.lastComput{run}.fromDataTreated(:, 1)));
                plot(app.ToPlot, app.data.lastComput{run}.fromDataTreated(:, 1), pred, ...
                    'DisplayName', strcat('Pred. ', nameTo), 'LineWidth', 2, 'Color', [0.6350, 0.0780, 0.1840]);
                timeTF = 0:app.deltaT.Value*0.001:(length(app.data.lastComput{run}.TF)-1)*app.deltaT.Value*0.001;
                
                plot(app.TFPlot, timeTF, app.data.lastComput{run}.TF, 'DisplayName', 'TF', 'LineWidth', 2, 'Color', [0.4940, 0.1840, 0.5560]);
                
                app.ResidualvalueEditField.Enable = 'on';
                app.ResidualvalueEditFieldLabel.Enable = 'on';
                app.ResidualvalueEditField.Value = app.data.lastComput{run}.finalSSResid;
                app.PearsonvalueEditField.Enable = 'on';
                app.PearsonvalueEditFieldLabel.Enable = 'on';
                app.PearsonvalueEditField.Value = app.data.lastComput{run}.pearson;
                
                app.updateComputedParamsTab(run);
            else
                % TODO Adapt this part to multiple path possibility
                nameFrom = app.data.From{1};
                nameTo = app.data.To{1};
                
                plot(app.FromPlot, app.data.From{2}, app.data.From{3}, 'DisplayName', nameFrom, 'LineWidth', 2, 'Color', [0, 0.4470, 0.7410]);
                plot(app.ToPlot, app.data.To{2}, app.data.To{3}, 'DisplayName', nameTo, 'LineWidth', 2, 'Color', [0.9290, 0.6940, 0.1250]);
                cla(app.TFPlot);
                app.updateComputedParamsTab();
            end
            
            % Updating the legend visible state
            if app.CheckShowLegend.Value
                legend(app.FromPlot); legend(app.ToPlot);
                
                % Show legend of TF Plot only if there is data plotted inside
                if ~isempty(app.TFPlot.Children)
                    legend(app.TFPlot);
                else
                    legend(app.TFPlot, 'off');
                end
            else
                legend(app.FromPlot, 'off'); legend(app.ToPlot, 'off'); legend(app.TFPlot, 'off');
            end
            
            
            hold(app.FromPlot, 'off'); hold(app.ToPlot, 'off'); hold(app.TFPlot, 'off');
        end
        
        
        function updateEnableLoadDisplayButton(app)
            % This function evaluates if the Load & Display button can be enabled, if there is enough information in the GUI
            
            if ~isempty(app.ToFilePath.Value) && ~isempty(app.ToDataPath.Value) && ...
                    (app.UseStepCheckbox.Value || (~isempty(app.FromFilePath.Value) && ~isempty(app.FromDataPath.Value)))
                app.ButtonDisplayData.Enable = 'on';
            else
                app.ButtonDisplayData.Enable = 'off';
            end
        end
        
        function updateParametersTab(app)

            idxFct = find(strcmp(app.FunctionDropDown.Items, app.FunctionDropDown.Value));
            if idxFct <= length(app.parametricFctHandles) || strcmp(app.FunctionDropDown.Value, 'Personalized')
                app.SetParamsTable.Data = app.computationParamDefaultValues{idxFct}.lastParams;
            else
                app.SetParamsTable.Data = [];
            end
        end
        
        function [paramsTF, lwBnd, upBnd] = extractParamsTable(app)
            paramsTF = table2array(app.SetParamsTable.Data(:, 2))';
            lwBnd = table2array(app.SetParamsTable.Data(:, 3))';
            upBnd = table2array(app.SetParamsTable.Data(:, 4))';   
        end
        
        function updateComputedParamsTab(app, i)
            if app.smthgComputed
                isNonParamFct = strcmp(app.data.lastComput{1}.genOptions.algo, ...
                    app.NON_PARAM_ALGO);
                if ~isNonParamFct
                    if exist('i')
                        app.ComputedParamsTable.Data = app.data.lastComput{i}.paramTF';
                    else
                        app.ComputedParamsTable.Data = app.data.lastComput{1}.paramTF';
                    end
                end
            else
                app.ComputedParamsTable.Data = [];
            end
        end
        
    end
    
    methods (Access = public)
        
        
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Reading and loading the parametric functions from the corresponding file.
            [paramFctNames, paramFctHandles] = readFunctionsFile(app.PARAM_FUNCTIONS_FILE);
            app.parametricFctHandles = paramFctHandles;
            
            app.FunctionDropDown.Items = {paramFctNames{:}, 'Toeplitz', 'Fourier', 'Personalized'};
            app.FunctionDropDown.Value = paramFctNames{1};
            app.paramFunctionStrEditField.Value = func2str(paramFctHandles{1});
            
            for i=1:length(paramFctHandles)
                app.computationParamDefaultValues{i} = struct();
                app.computationParamDefaultValues{i}.fctHandle = func2str(paramFctHandles{i});
                
                try
                    paramNames = extractParametersFromHandle(paramFctHandles{i});
                    paramNames = paramNames(1:end-1);
                    nbParams = length(paramNames);
                    paramArray = table(paramNames, repelem(2, nbParams)', repelem(0.001, nbParams)', repelem(10, nbParams)');
                    app.computationParamDefaultValues{i}.lastParams = paramArray;
                catch ME
                    app.SetParamsTable.Data = [];
                    
                    if strfind(ME.identifier, 'Iliski')
                        errordlg(ME.message, 'Function handle Error');
                      else
                          errordlg(getReport(ME), 'Matlab Error');
                    end
                end
            end
            
            app.updateParametersTab();
        end

        % Button pushed function: ButtonChooseFromFile
        function ButtonChooseFromFilePushed(app, event)
            [fileName, pathFile] = uigetfile('.h5', 'Select the From HDF5 File');
            app.FromFilePath.Value = [pathFile fileName];
            
            % If 'Same' is checked, will also change the value of the To file
            if app.CheckToSameAsFromFile.Value
                app.ToFilePath.Value = app.FromFilePath.Value;
            end
            
            if app.UseStepCheckbox.Value
                app.UseStepCheckbox.Value = 0;
                app.UseStepCheckboxValueChanged();
            end
            
            app.updateEnableLoadDisplayButton();
        end

        % Button pushed function: ButtonChooseToFile
        function ButtonChooseToFilePushed(app, event)
            [fileName, pathFile] = uigetfile('.h5', 'Select the To HDF5 File');
            app.ToFilePath.Value = [pathFile fileName];
            
            app.updateEnableLoadDisplayButton();
        end

        % Value changed function: CheckToSameAsFromFile
        function CheckToSameAsFromFileValueChanged(app, event)
            value = app.CheckToSameAsFromFile.Value;
            % If To file is the same as From file
            if value
                app.ToFilePath.Value = app.FromFilePath.Value;
                app.ToFilePath.Editable = false;
                app.ButtonChooseToFile.Enable = false;
            else
                app.ToFilePath.Editable = true;
                app.ButtonChooseToFile.Enable = true;
            end
            
            app.updateEnableLoadDisplayButton();
        end

        % Button pushed function: ButtonDisplayData, UpdateStepButton
        function ButtonDisplayDataPushed(app, event)
            app.data = struct();
            
            fileFrom = app.FromFilePath.Value;
            fileTo = app.ToFilePath.Value;
            
            FromPaths = app.FromDataPath.Value;
            ToPaths = app.ToDataPath.Value;
            
            % 3 different cases : single dataet, full folder, random acq/folders separated by commas
            % Final structure is a correspondance between the acq name and the time & timecourse.
            % Created some functions to do it but will just code the "single acq" functionality for now.
            
            %don't forget to take into account the Nrun spinner.
            
            if app.UseStepCheckbox.Value
                [timeVector, stepFct] = generateStepFunction(app.deltaT.Value/1000, app.BaselineStepSpinner.Value, ...
                    app.UpStepSpinner.Value, app.TotalSpinner.Value);
                stepFrom = {};
                stepFrom{1} = 'Step function';
                stepFrom{2} = timeVector;
                stepFrom{3} = stepFct;
                app.data.From = stepFrom;
            else
                app.data.From = loadSingledataset(app, fileFrom, FromPaths);
            end
            
            app.data.To = loadSingledataset(app, fileTo, ToPaths);
            
            app.smthgComputed = false;
            
            app.ComputeButton.Enable = 'on';
            app.SaveButton.Enable = 'off';
            app.CheckShowTreatedData.Enable = 'off'; app.CheckShowTreatedData.Value = false;
            
            app.ResidualvalueEditField.Enable = 'off';
            app.ResidualvalueEditFieldLabel.Enable = 'off';
            app.ResidualvalueEditField.Value = 0;
            app.PearsonvalueEditField.Enable = 'off';
            app.PearsonvalueEditFieldLabel.Enable = 'off';
            app.PearsonvalueEditField.Value = 0;
            
            app.PlotRunSpinner.Enable = 'off';
            app.PlotRunSpinnerLabel.Enable = 'off';
            app.PlotRunSpinner.Value = 1;
            
            app.UseLastComputParamsAsInitialButton.Enable = 'off';
            
            app.smthgLoaded = true;
            
            app.updatePlots();
        end

        % Button pushed function: ComputeButton
        function ComputeButtonPushed(app, event)
            options.saveOut = false;
            options.ruleOutImag = false;
            
            options.Nrun= app.NumberofrunsSpinner.Value;
            options.medianFilter_To = app.MedianFilterToSpinner.Value;
            options.sgolayPoints_To = app.SGolayPointsToSpinner.Value;
            options.medianFilter_From = app.MedianFilterFromSpinner.Value;
            options.sgolayPoints_From = app.SGolayPointsFromSpinner.Value;
            options.interpMethod = app.InterpolationMethodDropDown.Value; %'spline'/'pchip'/'linear'
            options.smoothDT = app.deltaT.Value * 0.001;
            options.range = [app.rangeDataBegin.Value app.rangeDataEnd.Value];
            options.durationTF = app.durationTF.Value;
            options.stepON = app.UseStepCheckbox.Value;
            options.twiceOpt = app.twiceOpt.Value;
            
            if strcmp(app.AlgorithmDropDown.Value, 'Simulated Annealing')
                options.algo = 'simulannealbnd';
            elseif strcmp(app.AlgorithmDropDown.Value, 'FMinUnc')
                options.algo = 'fminunc';
            elseif strcmp(app.AlgorithmDropDown.Value, 'FMinSearch')
                options.algo = 'fminsearch';
            elseif strcmp(app.AlgorithmDropDown.Value, 'FMinCon')
                options.algo = 'fmincon';
            end
            
            if app.UseStepCheckbox.Value
                options.medianFilter_From = 0;
                options.sgolayPoints_From = 0;
                options.interpMethod = 'None';
            end
            
            
            if strcmp(app.FunctionDropDown.Value, 'Toeplitz')
                options.func = 'toeplitz';
                options.algo = 'toeplitz';
            elseif strcmp(app.FunctionDropDown.Value, 'Fourier')
                options.func = 'fourier';
                options.algo = 'fourier';
            else
                try % If the Personalized function is not correct
                    if strcmp(app.FunctionDropDown.Value, 'Personalized')
                        options.func = str2func(app.paramFunctionStrEditField.Value);
                    else
                        idxFct = find(strcmp(app.FunctionDropDown.Items, app.FunctionDropDown.Value));
                        options.func = app.parametricFctHandles{idxFct};
                    end
                    [paramsTF, lwBnd, upBnd] = app.extractParamsTable();
                    options.paramsTF = paramsTF;
                    options.lwBnd = lwBnd;
                    options.upBnd = upBnd;
                catch ME
                    if strfind(ME.identifier, 'Iliski')
                        errordlg(ME.message, 'Personalized function Error')
                    else
                        errordlg(getReport(ME), 'Matlab Error');
                    end
                    return
                end
            end
            
            dataFrom = [app.data.From{2}' app.data.From{3}'];
            dataTo = [app.data.To{2}' app.data.To{3}'];
            
            % Progress bar
            progressBar = uiprogressdlg(app.BuildTF_GUIUIFigure, 'Title', 'Computing TF', ...
                'Indeterminate', 'on');
            resultsTF = buildTF(dataFrom, dataTo, options);
            close(progressBar);
            
            % Adding side Info that is not in the resultsTF struct
            for i=1:options.Nrun
                if app.UseStepCheckbox.Value
                    resultsTF{i}.fileFrom = 'Step function';
                    resultsTF{i}.pathFrom = '';
                else
                    resultsTF{i}.fileFrom = app.FromFilePath.Value;
                    resultsTF{i}.pathFrom = app.FromDataPath.Value;
                end
                resultsTF{i}.fileTo = app.ToFilePath.Value;
                resultsTF{i}.pathTo = app.ToDataPath.Value;
            end
            %%
            
            % Sorting the results by their sum of the square residual values, the first being the best one.
            app.data.lastComput = sortTFComputations(resultsTF, 'ascend');
            app.smthgComputed = true;
            app.CheckShowTreatedData.Enable = 'on';
            app.SaveButton.Enable = 'on';
            app.UseLastComputParamsAsInitialButton.Enable = 'on';
            
            %Updating the "Last Params" array to save the used parameters for an ulterior use in this same session
            idxFct = find(strcmp(app.FunctionDropDown.Items, app.FunctionDropDown.Value));
            if idxFct <= length(app.parametricFctHandles) || strcmp(app.FunctionDropDown.Value, 'Personalized')
                app.computationParamDefaultValues{idxFct}.lastParams = app.SetParamsTable.Data;
            end
            
            if strcmp(app.data.lastComput{end}.genOptions.algo, 'simulannealbnd') && app.NumberofrunsSpinner.Value > 1
                app.PlotRunSpinner.Enable = 'on';
                app.PlotRunSpinnerLabel.Enable = 'on';
                app.PlotRunSpinner.Limits = [1 app.data.lastComput{end}.genOptions.Nrun];
                app.PlotRunSpinner.Value = 1;
            else
                app.PlotRunSpinner.Enable = 'off';
                app.PlotRunSpinner.Value = 1;
            end
            
            app.updatePlots();
        end

        % Value changed function: PlotRunSpinner
        function PlotRunSpinnerValueChanged(app, event)
            value = app.PlotRunSpinner.Value;
            
            % Just update the plots
            app.updatePlots();
        end

        % Button pushed function: ButtonPlotOutFigures
        function ButtonPlotOutFiguresPushed(app, event)
            allChildren = app.FromPlot.XAxis.Parent.Children;
            if ~isempty(allChildren)
                fig = figure('Name', 'From', 'NumberTitle', 'off'); hold on;
                delete(gca);
                figAxes = axes(fig);
                copyobj(allChildren, figAxes); legend();
                hold off;
            end
            
            allChildren = app.ToPlot.XAxis.Parent.Children;
            if ~isempty(allChildren)
                fig = figure('Name', 'To', 'NumberTitle', 'off'); hold on;
                delete(gca);
                figAxes = axes(fig);
                copyobj(allChildren, figAxes); legend()
                hold off;
            end
            
            allChildren = app.TFPlot.XAxis.Parent.Children;
            if ~isempty(allChildren)
                fig = figure('Name', 'TF', 'NumberTitle', 'off'); hold on;
                delete(gca);
                figAxes = axes(fig);
                copyobj(allChildren, figAxes); legend()
                hold off;
            end
        end

        % Value changed function: CheckShowTreatedData
        function CheckShowTreatedDataValueChanged(app, event)
            value = app.CheckShowTreatedData.Value;
            if app.smthgLoaded
                app.updatePlots();
            end
        end

        % Value changed function: CheckShowLegend
        function CheckShowLegendValueChanged(app, event)
            value = app.CheckShowLegend.Value;
            app.updatePlots();
        end

        % Value changed function: FunctionDropDown
        function FunctionDropDownValueChanged(app, event)
            value = app.FunctionDropDown.Value;
            
            % If non-parametric functions
            if strcmp(value, 'Fourier') | strcmp(value, 'Toeplitz')
                app.AlgorithmDropDown.Enable = 'off';
                app.twiceOpt.Enable = 'on';
                app.twiceOpt.Value = false;
                app.paramFunctionStrEditField.Editable = 'off';
                app.paramFunctionStrEditField.Value = 'DECONVOLUTIONAL METHOD - NON-PARAMETRIC FUNCTION';
                app.UseLastComputParamsAsInitialButton.Enable = 'off';
                app.updateParametersTab();
            else
                app.AlgorithmDropDown.Enable = 'on';
                app.twiceOpt.Enable = 'on';
                
                if strcmp(value, 'Personalized')
                    app.paramFunctionStrEditField.Editable = 'on';
                    app.paramFunctionStrEditField.Value = '@(parameters) function';
                else
                    app.paramFunctionStrEditField.Editable = 'off';
                    idxFct = find(strcmp(app.FunctionDropDown.Items, value));
                    app.paramFunctionStrEditField.Value = func2str(app.parametricFctHandles{idxFct});
                    
                    if strcmp(app.paramFunctionStrEditField.Value, ...
                            func2str(app.data.lastComput{1}.genOptions.func))
                        app.UseLastComputParamsAsInitialButton.Enable = 'on';
                    else
                        app.UseLastComputParamsAsInitialButton.Enable = 'off';
                    end
                            
                    
                    app.updateParametersTab();
                end
            end
            
        end

        % Value changed function: AlgorithmDropDown
        function AlgorithmDropDownValueChanged(app, event)
            value = app.AlgorithmDropDown.Value;
            
            if strcmp(value, 'Simulated Annealing')
                app.NumberofrunsSpinner.Enable = 'on';
            else
                app.NumberofrunsSpinner.Enable = 'off';
                app.NumberofrunsSpinner.Value = 1;
            end
        end

        % Close request function: BuildTF_GUIUIFigure
        function BuildTF_GUIUIFigureCloseRequest(app, event)
            delete(app)
        end

        % Value changed function: twiceOpt
        function twiceOptValueChanged(app, event)
            value = app.twiceOpt.Value;
            if value
                app.AlgorithmDropDown.Value = 'Simulated Annealing';
                app.AlgorithmDropDownValueChanged();
                app.AlgorithmDropDown.Enable = 'off';
            else
                app.AlgorithmDropDown.Enable = 'on';
            end
            
        end

        % Button pushed function: ButtonChooseSaveFile
        function ButtonChooseSaveFilePushed(app, event)
            [fileName, pathFile] = uiputfile('.mat', 'Select an (in)existent file to save your results');
            app.SavingFileEditField.Value = [pathFile fileName];
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            % Checking if all parameter fields are filled
            if isempty(app.MouseEditField.Value) | isempty(app.TechniqueEditField.Value) | ...
                    isempty(app.StimulationEditField.Value)
                errordlg('All fields must be filled to save a result.', 'Saving error');
                % Checking if the saving file a '.mat' file
            elseif ~strcmp(app.SavingFileEditField.Value(end-3:end), '.mat')
                errordlg("Saving file must be a '.mat' file.");
            else
                try
                    matVar = load(app.SavingFileEditField.Value); % it looks for the output structure file
                catch
                    matVar.results = struct(); % if not found, it makes a new one
                end
                
                Modality = app.StimulationEditField.Value;
                Tech = app.TechniqueEditField.Value;
                Mouse = app.MouseEditField.Value;
                
                Algo = '';
                switch app.AlgorithmDropDown.Value
                    case 'Simulated Annealing'
                        Algo = 'simulannealbnd';
                    case 'FMinCon'
                        Algo = 'fmincon';
                    case 'FMinUnc'
                        Algo = 'fminunc';
                    case 'FMinSearch'
                        Algo = 'fminsearch';
                    otherwise
                        Algo = 'default';
                end
                
                Func = '';
                switch app.FunctionDropDown.Value
                    case '1-Gamma'
                        Func = 'gamma';
                    case '2-Logit'
                        Func = 'logit';
                    case 'Toeplitz'
                        Func = 'toeplitz';
                        Algo = 'topelitz';
                    case 'Fourier'
                        Func = 'fourier';
                        Algo = 'fourier';
                    otherwise
                        Func = 'default';
                end
                
                if app.twiceOpt.Value
                    try
                        matVar.results.(Modality).(Tech).(Mouse).(['DT' num2str(app.deltaT.Value)]).(Func).('simulannealbnd_fminunc') = ...
                            [matVar.results.(Modality).(Tech).(Mouse).(['DT' num2str(app.deltaT.Value)]).(Func).('simulannealbnd_fminunc') ...
                            app.data.lastComput];
                    catch ME
                        if strcmp(ME.identifier, 'MATLAB:nonExistentField')
                            matVar.results.(Modality).(Tech).(Mouse).(['DT' num2str(app.deltaT.Value)]).(Func).('simulannealbnd_fminunc') = ...
                                app.data.lastComput;
                        else
                            rethrow(ME);
                        end
                    end
                else
                    try
                        matVar.results.(Modality).(Tech).(Mouse).(['DT' num2str(app.deltaT.Value)]).(Func).(Algo) = ...
                            [matVar.results.(Modality).(Tech).(Mouse).(['DT' num2str(app.deltaT.Value)]).(Func).(Algo) ...
                            app.data.lastComput];
                    catch ME
                        if strcmp(ME.identifier, 'MATLAB:nonExistentField')
                            matVar.results.(Modality).(Tech).(Mouse).(['DT' num2str(app.deltaT.Value)]).(Func).(Algo) = ...
                                app.data.lastComput;
                        else
                            rethrow(ME);
                        end
                    end
                end
                results = matVar.results;
                save(app.SavingFileEditField.Value, 'results');
            end
        end

        % Value changed function: TotalSpinner
        function TotalSpinnerValueChanged(app, event)
            value = app.TotalSpinner.Value;
            minimumDuration = app.BaselineStepSpinner.Value + app.UpStepSpinner.Value;
            
            if value < minimumDuration
                app.TotalSpinner.Value = minimumDuration;
                errorMsg = 'Total duration of the step function should be at least equal to the sum of both the baseline and the up state. Value is corrected to this minimum.';
                errordlg(errorMsg, 'Step function generator Error');
            end
        end

        % Value changed function: UseStepCheckbox
        function UseStepCheckboxValueChanged(app, event)
            value = app.UseStepCheckbox.Value;
            
            if value
                app.UpdateStepButton.Enable = 'on';
                app.FromDataPath.Enable = 'off';
                app.CheckToSameAsFromFile.Enable = 'off';
                app.SGolayPointsFromSpinner.Enable = 'off';
                app.MedianFilterFromSpinner.Enable = 'off';
                app.InterpolationMethodDropDown.Enable = 'off';
            else
                app.UpdateStepButton.Enable = 'off';
                app.FromDataPath.Enable = 'on';
                app.CheckToSameAsFromFile.Enable = 'on';
                app.SGolayPointsFromSpinner.Enable = 'on';
                app.MedianFilterFromSpinner.Enable = 'on';
                app.InterpolationMethodDropDown.Enable = 'on';
            end
            
            app.updateEnableLoadDisplayButton();
        end

        % Value changed function: SGolayPointsToSpinner
        function SGolayPointsToSpinnerValueChanged(app, event)
            value = app.SGolayPointsToSpinner.Value;
            
            % Checking if the SGolay parameter is an odd number, as it should be. Adding 1 and error dlg otherwise
            if mod(value, 2) == 0 && value ~= 0 % Even number
                app.SGolayPointsToSpinner.Value = value + 1;
                errorMsg = {'SGolay filter requires an odd number of points as the frame length parameter. Value is corrected by adding 1.'; 'See https://fr.mathworks.com/help/signal/ref/sgolayfilt.html for details.'};
                errordlg(errorMsg, 'SGolay filter Error');
            end
            
            % Changing the step to avoid even numbers
            if value == 0
                app.SGolayPointsToSpinner.Step = 1;
            else
                app.SGolayPointsToSpinner.Step = 2;
            end
        end

        % Value changed function: SGolayPointsFromSpinner
        function SGolayPointsFromSpinnerValueChanged(app, event)
            value = app.SGolayPointsFromSpinner.Value;
            
            % Checking if the SGolay parameter is an odd number, as it should be. Adding 1 and error dlg otherwise
            if mod(value, 2) == 0 && value ~= 0 % Even number
                app.SGolayPointsFromSpinner.Value = value + 1;
                errorMsg = {'SGolay filter requires an odd number of points as the frame length parameter. Value is corrected by adding 1.'; 'See https://fr.mathworks.com/help/signal/ref/sgolayfilt.html for details.'};
                errordlg(errorMsg, 'SGolay filter Error');
            end
            
            % Changing the step to avoid even numbers
            if value == 0
                app.SGolayPointsFromSpinner.Step = 1;
            else
                app.SGolayPointsFromSpinner.Step = 2;
            end
        end

        % Value changed function: FromFilePath
        function FromFilePathValueChanged(app, event)
            value = app.FromFilePath.Value;
            % If 'Same' is checked, will also change the value of the To file
            if app.CheckToSameAsFromFile.Value
                app.ToFilePath.Value = app.FromFilePath.Value;
            end
            app.updateEnableLoadDisplayButton();
        end

        % Value changed function: ToFilePath
        function ToFilePathValueChanged(app, event)
            value = app.ToFilePath.Value;
            app.updateEnableLoadDisplayButton();
        end

        % Value changed function: FromDataPath
        function FromDataPathValueChanged(app, event)
            value = app.FromDataPath.Value;
            app.updateEnableLoadDisplayButton();
        end

        % Value changed function: ToDataPath
        function ToDataPathValueChanged(app, event)
            value = app.ToDataPath.Value;
            app.updateEnableLoadDisplayButton();
        end

        % Value changed function: paramFunctionStrEditField
        function paramFunctionStrEditFieldValueChanged(app, event)
            value = app.paramFunctionStrEditField.Value;
            app.updateParametersTab();
        end

        % Button pushed function: UseLastComputParamsAsInitialButton
        function UseLastComputParamsAsInitialButtonPushed(app, event)
            app.SetParamsTable.Data(:, 2) = table(app.ComputedParamsTable.Data);         
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.BuildTF_GUIUIFigure = uifigure;
            app.BuildTF_GUIUIFigure.Name = 'BuildTF_GUI';
            app.BuildTF_GUIUIFigure.CloseRequestFcn = createCallbackFcn(app, @BuildTF_GUIUIFigureCloseRequest, true);
            app.FromHDF5EditFieldLabel = uilabel(app.BuildTF_GUIUIFigure);
            app.FromHDF5EditFieldLabel.Text = 'From HDF5';
            app.FromFilePath = uieditfield(app.BuildTF_GUIUIFigure, 'text');
            app.FromFilePath.ValueChangedFcn = createCallbackFcn(app, @FromFilePathValueChanged, true);
            app.FromFilePath.Value = 'C:\Users\ali-k\OneDrive\PhD_Charpak\Iliski_TF\iliski\XV4.h5';
            app.ButtonChooseFromFile = uibutton(app.BuildTF_GUIUIFigure, 'push');
            app.ButtonChooseFromFile.ButtonPushedFcn = createCallbackFcn(app, @ButtonChooseFromFilePushed, true);
            app.ButtonChooseFromFile.Text = 'Browse';
            app.ToHDF5EditFieldLabel = uilabel(app.BuildTF_GUIUIFigure);
            app.ToHDF5EditFieldLabel.Text = 'To HDF5';
            app.ToFilePath = uieditfield(app.BuildTF_GUIUIFigure, 'text');
            app.ToFilePath.ValueChangedFcn = createCallbackFcn(app, @ToFilePathValueChanged, true);
            app.ToFilePath.Value = 'C:\Users\ali-k\OneDrive\PhD_Charpak\Iliski_TF\iliski\XV4.h5';
            app.ButtonChooseToFile = uibutton(app.BuildTF_GUIUIFigure, 'push');
            app.ButtonChooseToFile.ButtonPushedFcn = createCallbackFcn(app, @ButtonChooseToFilePushed, true);
            app.ButtonChooseToFile.Text = 'Browse';
            app.CheckToSameAsFromFile = uicheckbox(app.BuildTF_GUIUIFigure);
            app.CheckToSameAsFromFile.ValueChangedFcn = createCallbackFcn(app, @CheckToSameAsFromFileValueChanged, true);
            app.CheckToSameAsFromFile.Text = 'Same';
            app.PathFromEditFieldLabel = uilabel(app.BuildTF_GUIUIFigure);
            app.PathFromEditFieldLabel.Text = 'Path - From';
            app.FromDataPath = uieditfield(app.BuildTF_GUIUIFigure, 'text');
            app.FromDataPath.ValueChangedFcn = createCallbackFcn(app, @FromDataPathValueChanged, true);
            app.FromDataPath.Value = '/Delta/Ca/ET_200mV_5sec/avg';
            app.PathToEditFieldLabel = uilabel(app.BuildTF_GUIUIFigure);
            app.PathToEditFieldLabel.Text = 'Path - To';
            app.ToDataPath = uieditfield(app.BuildTF_GUIUIFigure, 'text');
            app.ToDataPath.ValueChangedFcn = createCallbackFcn(app, @ToDataPathValueChanged, true);
            app.ToDataPath.Value = '/DeltaOverBSL/RBC/ET_200mV_5sec/avg';
            app.ButtonDisplayData = uibutton(app.BuildTF_GUIUIFigure, 'push');
            app.ButtonDisplayData.ButtonPushedFcn = createCallbackFcn(app, @ButtonDisplayDataPushed, true);
            app.ButtonDisplayData.Text = 'Load & Display Data';
            app.ButtonPlotOutFigures = uibutton(app.BuildTF_GUIUIFigure, 'push');
            app.ButtonPlotOutFigures.ButtonPushedFcn = createCallbackFcn(app, @ButtonPlotOutFiguresPushed, true);
            app.ButtonPlotOutFigures.Text = 'Plot Out Figures';
            app.ComputeButton = uibutton(app.BuildTF_GUIUIFigure, 'push');
            app.ComputeButton.ButtonPushedFcn = createCallbackFcn(app, @ComputeButtonPushed, true);
            app.ComputeButton.Enable = 'off';
            app.ComputeButton.Text = 'Compute TF';
            app.CheckShowLegend = uicheckbox(app.BuildTF_GUIUIFigure);
            app.CheckShowLegend.ValueChangedFcn = createCallbackFcn(app, @CheckShowLegendValueChanged, true);
            app.CheckShowLegend.Text = 'Show legend';
            app.CheckShowLegend.Value = true;
            app.CheckShowTreatedData = uicheckbox(app.BuildTF_GUIUIFigure);
            app.CheckShowTreatedData.ValueChangedFcn = createCallbackFcn(app, @CheckShowTreatedDataValueChanged, true);
            app.CheckShowTreatedData.Enable = 'off';
            app.CheckShowTreatedData.Text = 'Show treated data';
            app.PlotRunSpinnerLabel = uilabel(app.BuildTF_GUIUIFigure);
            app.PlotRunSpinnerLabel.Enable = 'off';
            app.PlotRunSpinnerLabel.Text = 'Plot Run';
            app.PlotRunSpinner = uispinner(app.BuildTF_GUIUIFigure);
            app.PlotRunSpinner.Limits = [1 Inf];
            app.PlotRunSpinner.ValueChangedFcn = createCallbackFcn(app, @PlotRunSpinnerValueChanged, true);
            app.PlotRunSpinner.Enable = 'off';
            app.PlotRunSpinner.Value = 1;
            app.ResidualvalueEditFieldLabel = uilabel(app.BuildTF_GUIUIFigure);
            app.ResidualvalueEditFieldLabel.Enable = 'off';
            app.ResidualvalueEditFieldLabel.Text = 'Residual value';
            app.ResidualvalueEditField = uieditfield(app.BuildTF_GUIUIFigure, 'numeric');
            app.ResidualvalueEditField.ValueDisplayFormat = '%.5f';
            app.ResidualvalueEditField.Editable = 'off';
            app.ResidualvalueEditField.Enable = 'off';
            app.PearsonvalueEditFieldLabel = uilabel(app.BuildTF_GUIUIFigure);
            app.PearsonvalueEditFieldLabel.Enable = 'off';
            app.PearsonvalueEditFieldLabel.Text = 'Pearson value';
            app.PearsonvalueEditField = uieditfield(app.BuildTF_GUIUIFigure, 'numeric');
            app.PearsonvalueEditField.ValueDisplayFormat = '%.5f';
            app.PearsonvalueEditField.Editable = 'off';
            app.PearsonvalueEditField.Enable = 'off';
            app.DataTFPanel = uipanel(app.BuildTF_GUIUIFigure);
            app.DataTFPanel.Title = 'Data & TF';
            app.FromPlot = uiaxes(app.DataTFPanel);
            title(app.FromPlot, 'From')
            xlabel(app.FromPlot, 'Time (s)')
            app.ToPlot = uiaxes(app.DataTFPanel);
            title(app.ToPlot, 'To')
            xlabel(app.ToPlot, 'Time (s)')
            app.TFPlot = uiaxes(app.DataTFPanel);
            title(app.TFPlot, 'TF')
            xlabel(app.TFPlot, 'Time (s)')
            app.AboutButton = uibutton(app.BuildTF_GUIUIFigure, 'push');
            app.AboutButton.Text = 'About';
            app.HelpButton = uibutton(app.BuildTF_GUIUIFigure, 'push');
            app.HelpButton.Text = 'Help';
            app.TabGroup = uitabgroup(app.BuildTF_GUIUIFigure);
            app.PretreatmentTab = uitab(app.TabGroup);
            app.PretreatmentTab.Title = 'Pre-treatment';
            app.ToPanel = uipanel(app.PretreatmentTab);
            app.ToPanel.Title = 'To';
            app.SGolayFilterptsLabel_2 = uilabel(app.ToPanel);
            app.SGolayFilterptsLabel_2.Text = 'SGolay Filter (pts)';
            app.SGolayPointsToSpinner = uispinner(app.ToPanel);
            app.SGolayPointsToSpinner.Limits = [0 Inf];
            app.SGolayPointsToSpinner.ValueChangedFcn = createCallbackFcn(app, @SGolayPointsToSpinnerValueChanged, true);
            app.MedianFilterptsLabel_2 = uilabel(app.ToPanel);
            app.MedianFilterptsLabel_2.Text = 'Median Filter (pts)';
            app.MedianFilterToSpinner = uispinner(app.ToPanel);
            app.MedianFilterToSpinner.Limits = [0 Inf];
            app.FromStepfunctiongeneratorPanel = uipanel(app.PretreatmentTab);
            app.FromStepfunctiongeneratorPanel.Title = 'From : Step function generator ';
            app.UseStepCheckbox = uicheckbox(app.FromStepfunctiongeneratorPanel);
            app.UseStepCheckbox.ValueChangedFcn = createCallbackFcn(app, @UseStepCheckboxValueChanged, true);
            app.UseStepCheckbox.Text = 'Use step as From signal';
            app.UpdateStepButton = uibutton(app.FromStepfunctiongeneratorPanel, 'push');
            app.UpdateStepButton.ButtonPushedFcn = createCallbackFcn(app, @ButtonDisplayDataPushed, true);
            app.UpdateStepButton.Enable = 'off';
            app.UpdateStepButton.Text = 'Update';
            app.BaselinesSpinnerLabel = uilabel(app.FromStepfunctiongeneratorPanel);
            app.BaselinesSpinnerLabel.Text = 'Baseline (s)';
            app.BaselineStepSpinner = uispinner(app.FromStepfunctiongeneratorPanel);
            app.BaselineStepSpinner.Limits = [0 Inf];
            app.BaselineStepSpinner.Value = 10;
            app.UpsSpinnerLabel = uilabel(app.FromStepfunctiongeneratorPanel);
            app.UpsSpinnerLabel.Text = 'Up (s)';
            app.UpStepSpinner = uispinner(app.FromStepfunctiongeneratorPanel);
            app.UpStepSpinner.Limits = [0 Inf];
            app.UpStepSpinner.Value = 5;
            app.TotalsSpinnerLabel = uilabel(app.FromStepfunctiongeneratorPanel);
            app.TotalsSpinnerLabel.Text = 'Total (s)';
            app.TotalSpinner = uispinner(app.FromStepfunctiongeneratorPanel);
            app.TotalSpinner.Limits = [0 Inf];
            app.TotalSpinner.ValueChangedFcn = createCallbackFcn(app, @TotalSpinnerValueChanged, true);
            app.TotalSpinner.Value = 30;
            app.FromPanel = uipanel(app.PretreatmentTab);
            app.FromPanel.Title = 'From';
            app.SGolayFilterptsLabel = uilabel(app.FromPanel);
            app.SGolayFilterptsLabel.Text = 'SGolay Filter (pts)';
            app.SGolayPointsFromSpinner = uispinner(app.FromPanel);
            app.SGolayPointsFromSpinner.Limits = [0 Inf];
            app.SGolayPointsFromSpinner.ValueChangedFcn = createCallbackFcn(app, @SGolayPointsFromSpinnerValueChanged, true);
            app.MedianFilterptsLabel = uilabel(app.FromPanel);
            app.MedianFilterptsLabel.Text = 'Median Filter (pts)';
            app.MedianFilterFromSpinner = uispinner(app.FromPanel);
            app.MedianFilterFromSpinner.Limits = [0 Inf];
            app.InterpolationMethodDropDownLabel = uilabel(app.FromPanel);
            app.InterpolationMethodDropDownLabel.Text = 'Interpolation Method';
            app.InterpolationMethodDropDown = uidropdown(app.FromPanel);
            app.InterpolationMethodDropDown.Items = {'spline', 'pchip', 'linear'};
            app.InterpolationMethodDropDown.Value = 'spline';
            app.NewdTmsSpinnerLabel = uilabel(app.FromPanel);
            app.NewdTmsSpinnerLabel.Text = 'New dT (ms)';
            app.deltaT = uispinner(app.FromPanel);
            app.deltaT.Step = 10;
            app.deltaT.Limits = [1 Inf];
            app.deltaT.Value = 100;
            app.Settingtheparametersvalueto0cancelthefilteringLabel = uilabel(app.PretreatmentTab);
            app.Settingtheparametersvalueto0cancelthefilteringLabel.Text = 'Setting the parameters value to 0 cancel the filtering.';
            app.BothPanel = uipanel(app.PretreatmentTab);
            app.BothPanel.Title = 'Both';
            app.RangefromsLabel = uilabel(app.BothPanel);
            app.RangefromsLabel.Text = 'Range from (s)';
            app.rangeDataBegin = uispinner(app.BothPanel);
            app.rangeDataBegin.Step = 0.5;
            app.rangeDataBegin.Value = 5;
            app.toLabel = uilabel(app.BothPanel);
            app.toLabel.Text = 'to ';
            app.rangeDataEnd = uispinner(app.BothPanel);
            app.rangeDataEnd.Step = 0.5;
            app.rangeDataEnd.Value = 27;
            app.TFComputationTab = uitab(app.TabGroup);
            app.TFComputationTab.Title = 'TF Computation';
            app.TFComputationPanel = uipanel(app.TFComputationTab);
            app.TFComputationPanel.Title = 'TF Computation';
            app.DurationsLabel = uilabel(app.TFComputationPanel);
            app.DurationsLabel.Text = 'Duration (s)';
            app.durationTF = uispinner(app.TFComputationPanel);
            app.durationTF.Step = 0.5;
            app.durationTF.Value = 15;
            app.NumberofrunsSpinnerLabel = uilabel(app.TFComputationPanel);
            app.NumberofrunsSpinnerLabel.Text = 'Number of runs';
            app.NumberofrunsSpinner = uispinner(app.TFComputationPanel);
            app.NumberofrunsSpinner.Limits = [1 Inf];
            app.NumberofrunsSpinner.Value = 1;
            app.FunctionDropDownLabel = uilabel(app.TFComputationPanel);
            app.FunctionDropDownLabel.Text = 'Function';
            app.FunctionDropDown = uidropdown(app.TFComputationPanel);
            app.FunctionDropDown.Items = {};
            app.FunctionDropDown.ValueChangedFcn = createCallbackFcn(app, @FunctionDropDownValueChanged, true);
            app.FunctionDropDown.Value = {};
            app.AlgorithmDropDownLabel = uilabel(app.TFComputationPanel);
            app.AlgorithmDropDownLabel.Text = 'Algorithm';
            app.AlgorithmDropDown = uidropdown(app.TFComputationPanel);
            app.AlgorithmDropDown.Items = {'Simulated Annealing', 'FMinUnc', 'FMinSearch', 'FMinCon'};
            app.AlgorithmDropDown.ValueChangedFcn = createCallbackFcn(app, @AlgorithmDropDownValueChanged, true);
            app.AlgorithmDropDown.Value = 'Simulated Annealing';
            app.UseLastComputParamsAsInitialButton = uibutton(app.TFComputationPanel, 'push');
            app.UseLastComputParamsAsInitialButton.ButtonPushedFcn = createCallbackFcn(app, @UseLastComputParamsAsInitialButtonPushed, true);
            app.UseLastComputParamsAsInitialButton.Enable = 'off';
            app.UseLastComputParamsAsInitialButton.Text = 'Use computed parameters as initial';
            app.twiceOpt = uicheckbox(app.TFComputationPanel);
            app.twiceOpt.ValueChangedFcn = createCallbackFcn(app, @twiceOptValueChanged, true);
            app.twiceOpt.Text = {'Simulated and '; 'then FMinUnc'};
            app.SetParamsTable = uitable(app.TFComputationTab);
            app.SetParamsTable.ColumnName = {'Parameter #'; 'Starting Value'; 'Lower bound'; 'Upper bound'};
            app.SetParamsTable.RowName = {};
            app.SetParamsTable.ColumnEditable = [false true true true];
            app.ComputedParamsTable = uitable(app.TFComputationTab);
            app.ComputedParamsTable.ColumnName = {'Computed'};
            app.ComputedParamsTable.RowName = {};
            app.EditFieldLabel = uilabel(app.TFComputationTab);
            app.EditFieldLabel.Text = '';
            app.paramFunctionStrEditField = uieditfield(app.TFComputationTab, 'text');
            app.paramFunctionStrEditField.ValueChangedFcn = createCallbackFcn(app, @paramFunctionStrEditFieldValueChanged, true);
            app.paramFunctionStrEditField.Editable = 'off';
            app.SavingTab = uitab(app.TabGroup);
            app.SavingTab.Title = 'Saving';
            app.SavingPanel = uipanel(app.SavingTab);
            app.SavingPanel.Title = 'Saving';
            app.MouseEditFieldLabel = uilabel(app.SavingPanel);
            app.MouseEditFieldLabel.Text = 'Mouse';
            app.MouseEditField = uieditfield(app.SavingPanel, 'text');
            app.MouseEditField.Value = 'XV4';
            app.TechniqueEditFieldLabel = uilabel(app.SavingPanel);
            app.TechniqueEditFieldLabel.Text = 'Technique';
            app.TechniqueEditField = uieditfield(app.SavingPanel, 'text');
            app.TechniqueEditField.Value = 'Ca_RBC';
            app.StimulationEditFieldLabel = uilabel(app.SavingPanel);
            app.StimulationEditFieldLabel.Text = 'Stimulation';
            app.StimulationEditField = uieditfield(app.SavingPanel, 'text');
            app.StimulationEditField.Value = 'ET_200mV_5sec';
            app.SaveButton = uibutton(app.SavingPanel, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Enable = 'off';
            app.SaveButton.Text = 'Save';
            app.FileEditFieldLabel = uilabel(app.SavingPanel);
            app.FileEditFieldLabel.Text = 'File';
            app.SavingFileEditField = uieditfield(app.SavingPanel, 'text');
            app.SavingFileEditField.Value = 'C:\Users\ali-k\Desktop\test.mat';
            app.ButtonChooseSaveFile = uibutton(app.SavingPanel, 'push');
            app.ButtonChooseSaveFile.ButtonPushedFcn = createCallbackFcn(app, @ButtonChooseSaveFilePushed, true);
            app.ButtonChooseSaveFile.Text = 'Browse';
        end
    end

    methods (Access = public)

        % Construct app
        function app = Full

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.BuildTF_GUIUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.BuildTF_GUIUIFigure)
        end
    end
end